classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GradientsPanel                matlab.ui.container.Panel
        Panel_3                       matlab.ui.container.Panel
        FrequencyEnable               matlab.ui.control.StateButton
        FrequencyShape                matlab.ui.container.ButtonGroup
        frequencyLinearButton         matlab.ui.control.RadioButton
        frequencyLogButton            matlab.ui.control.RadioButton
        FrequencySlant                matlab.ui.control.Slider
        Panel_2                       matlab.ui.container.Panel
        AmplitudeEnable               matlab.ui.control.StateButton
        AmplitudeShape                matlab.ui.container.ButtonGroup
        amplitudeLinearButton         matlab.ui.control.RadioButton
        amplitudeLogButton            matlab.ui.control.RadioButton
        AmplitudeSlant                matlab.ui.control.Slider
        Panel                         matlab.ui.container.Panel
        NoiseEnable                   matlab.ui.control.StateButton
        NoiseShape                    matlab.ui.container.ButtonGroup
        noiseLinearButton             matlab.ui.control.RadioButton
        noiseLogButton                matlab.ui.control.RadioButton
        NoiseSlant                    matlab.ui.control.Slider
        TexturePanel                  matlab.ui.container.Panel
        DefaultSpatialFrequencyLabel  matlab.ui.control.Label
        DefaultFrequency              matlab.ui.control.Slider
        UIAxes                        matlab.ui.control.UIAxes
    end


    properties (Access = private)
        port;               % tpad port
        x; fs;              % texture parameters (static)
        a; f; y; n; s;      % texture parameters (variable)
        fshape; ashape; nshape; % linearity parameters
        f1;f2; a1;a2; n1;n2;    % slant parameters
    end

    methods (Access = private)
    
        % UPDATE TEXTURE DISPLAY
        function updateTexture(app)
            %put all gradient effects together
            app.y = 2*app.n + app.a.*app.s;

            %plot
            plot(app.UIAxes,app.x,app.y);
            
            %send to tpad
            roughness = 2^15*app.y;
            DCfriction = 2^15;
            gradient = zeros(length(roughness),1);
            [result] = loadData(app.port,DCfriction,gradient,roughness);
            
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %connect to tpad
            d = [19200 5.3]; app.port = 1;
            [p, d] = connect2tpad('COM6'); app.port = p;
            
            %initialize default parameters
            app.x = (0:d(1)-1)*d(2)*1e-3;               %space array (mm)
            %app.f = app.DefaultFrequency.Value + .001;  %default frequency (1/mm) 
            app.f = 10^app.DefaultFrequency.Value;
            app.s = sin(2*pi*app.f*app.x);              %initial texture array
            
            %initialize frequency gradient parameters
            app.fshape = 'log'; 
            app.f1 = 3^-(2*app.FrequencySlant.Value); 
            app.f2 = 3^(2*app.FrequencySlant.Value);
            
            %initialize amplitude gradient parameters
            app.a = .5*ones(size(app.x));   
            app.ashape = 'linear';
            app.a1 = 0.5 - app.AmplitudeSlant.Value/2.001; 
            app.a2 = 0.5 + app.AmplitudeSlant.Value/2.001;
            
            %initialize noise gradient parameters
            app.n = zeros(1,length(app.x));
            app.nshape = 'linear';
            app.n1 = 0.5 - app.NoiseSlant.Value/2.001; 
            app.n2 = 0.5 + app.NoiseSlant.Value/2.001;
            
            %set up plot display
            app.UIAxes.XLim = [0 104]; app.UIAxes.YLim = [-1 1];
            xlabel(app.UIAxes, 'Position (mm)');
            ylabel(app.UIAxes, 'Friction Force');
            app.UIAxes.YTick = [];
            title(app.UIAxes,'');
            app.UIAxes.CameraPositionMode = 'auto';
            app.UIAxes.CameraTargetMode = 'auto';
            app.UIAxes.XTickMode = 'auto';
            app.UIAxes.ZLimMode = 'auto';
            app.UIAxes.DataAspectRatioMode = 'auto';
            app.UIAxes.PlotBoxAspectRatioMode = 'auto';
            
            %load default texture onto tpad
            updateTexture(app)
        end

        % Value changed function: DefaultFrequency
        function DefaultFrequencyValueChanged(app, event)
            %SET DEFAULT FREQUENCY OF TEXTURE (UNRELATED TO GRADIENTS)
            %app.f = app.DefaultFrequency.Value + .001;
            app.f = 10^app.DefaultFrequency.Value;
            FrequencyEnableValueChanged(app, event)
        end

        % Value changed function: FrequencyEnable
        function FrequencyEnableValueChanged(app, event)
            %ENABLE/DISABLE FREQUENCY GRADIENT
            if app.FrequencyEnable.Value == true
                app.s = chirp(app.x,app.f1,app.x(end),app.f2,app.fshape);
                app.FrequencyEnable.BackgroundColor = [.8 1 .8];
            else
                app.s = sin(2*pi*app.f*app.x);
                app.FrequencyEnable.BackgroundColor = [1 .8 .8];
            end
            updateTexture(app)
        end

        % Selection changed function: FrequencyShape
        function FrequencyShapeSelectionChanged(app, event)
            %CHANGE FREQUENCY GRADIENT TO LINEAR OR LOG SHAPE
            selectedButton = app.FrequencyShape.SelectedObject;
            app.fshape = selectedButton.Text;
            FrequencyEnableValueChanged(app, event)
        end

        % Value changed function: FrequencySlant
        function FrequencySlantValueChanged(app, event)
            %CHANGE FREQUENCY GRADIENT SLANT DIRECTION (TO LEFT OR RIGHT)
            app.f1 = 3^-(2*app.FrequencySlant.Value); 
            app.f2 = 3^(2*app.FrequencySlant.Value);
            FrequencyEnableValueChanged(app, event)
        end

        % Value changed function: AmplitudeEnable
        function AmplitudeEnableValueChanged(app, event)
            %ENABLE/DISABLE AMPLITUDE GRADIENT
            if app.AmplitudeEnable.Value == true
                app.AmplitudeEnable.BackgroundColor = [.8 1 .8];
                if app.ashape(1:3) == 'lin'
                    app.a = linspace(app.a1,app.a2,length(app.x));
                else
                    app.a = logspace(log10(app.a1),log10(app.a2),length(app.x));
                end
            else 
                app.a = .5*ones(size(app.x)); 
                app.AmplitudeEnable.BackgroundColor = [1 .8 .8];
            end
            updateTexture(app)
        end

        % Selection changed function: AmplitudeShape
        function AmplitudeShapeSelectionChanged(app, event)
            %CHANGE AMPLITUDE GRADIENT TO LINEAR OR LOG SHAPE
            selectedButton = app.AmplitudeShape.SelectedObject;
            app.ashape = selectedButton.Text;
            AmplitudeEnableValueChanged(app, event)
        end

        % Value changed function: AmplitudeSlant
        function AmplitudeSlantValueChanged(app, event)
            %CHANGE AMPLITUDE GRADIENT SLANT DIRECTION (TO LEFT OR RIGHT)
            app.a1 = 0.5 - app.AmplitudeSlant.Value/2.001; 
            app.a2 = 0.5 + app.AmplitudeSlant.Value/2.001;
            AmplitudeEnableValueChanged(app, event)
        end

        % Value changed function: NoiseEnable
        function NoiseEnableValueChanged(app, event)
            %ENABLE/DISABLE NOISE GRADIENT
            if app.NoiseEnable.Value == true
                app.NoiseEnable.BackgroundColor = [.8 1 .8];
                base_noise = randn(1,length(app.x));
                if app.nshape(1:3) == 'lin'
                    amp_envelope = linspace(app.n1,app.n2,length(app.x));
                else
                    amp_envelope = logspace(log10(app.n1),log10(app.n2),length(app.x));
                end
                app.n = base_noise/max(base_noise).*amp_envelope.*2;
            else 
                app.n = zeros(1,length(app.x));
                app.NoiseEnable.BackgroundColor = [1 .8 .8];
            end
            updateTexture(app)       
        end

        % Selection changed function: NoiseShape
        function NoiseShapeSelectionChanged(app, event)
            %CHANGE NOISE GRADIENT TO LINEAR OR LOG SHAPE
            selectedButton = app.NoiseShape.SelectedObject;
            app.nshape = selectedButton.Text;
            NoiseEnableValueChanged(app, event)
        end

        % Value changed function: NoiseSlant
        function NoiseSlantValueChanged(app, event)
            %CHANGE NOISE GRADIENT SLANT DIRECTION (TO LEFT OR RIGHT)
            app.n1 = 0.5 - app.NoiseSlant.Value/2.001; 
            app.n2 = 0.5 + app.NoiseSlant.Value/2.001;
            NoiseEnableValueChanged(app, event)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.GradientsPanel = uipanel(app.UIFigure);
            app.GradientsPanel.Title = 'Gradients';
            app.Panel_3 = uipanel(app.GradientsPanel);
            app.FrequencyEnable = uibutton(app.Panel_3, 'state');
            app.FrequencyEnable.ValueChangedFcn = createCallbackFcn(app, @FrequencyEnableValueChanged, true);
            app.FrequencyEnable.Text = 'Frequency Gradient';
            app.FrequencyShape = uibuttongroup(app.Panel_3);
            app.FrequencyShape.SelectionChangedFcn = createCallbackFcn(app, @FrequencyShapeSelectionChanged, true);
            app.frequencyLinearButton = uiradiobutton(app.FrequencyShape);
            app.frequencyLinearButton.Text = 'linear';
            app.frequencyLogButton = uiradiobutton(app.FrequencyShape);
            app.frequencyLogButton.Text = 'log';
            app.frequencyLogButton.Value = true;
            app.FrequencySlant = uislider(app.Panel_3);
            app.FrequencySlant.Limits = [-1 1];
            app.FrequencySlant.MajorTicks = [-1 0 1];
            app.FrequencySlant.MajorTickLabels = {'Leftward', '0', 'Rightward'};
            app.FrequencySlant.ValueChangedFcn = createCallbackFcn(app, @FrequencySlantValueChanged, true);
            app.FrequencySlant.Value = -0.8;
            app.Panel_2 = uipanel(app.GradientsPanel);
            app.AmplitudeEnable = uibutton(app.Panel_2, 'state');
            app.AmplitudeEnable.ValueChangedFcn = createCallbackFcn(app, @AmplitudeEnableValueChanged, true);
            app.AmplitudeEnable.Text = 'Amplitude Gradient';
            app.AmplitudeShape = uibuttongroup(app.Panel_2);
            app.AmplitudeShape.SelectionChangedFcn = createCallbackFcn(app, @AmplitudeShapeSelectionChanged, true);
            app.amplitudeLinearButton = uiradiobutton(app.AmplitudeShape);
            app.amplitudeLinearButton.Text = 'linear';
            app.amplitudeLinearButton.Value = true;
            app.amplitudeLogButton = uiradiobutton(app.AmplitudeShape);
            app.amplitudeLogButton.Text = 'log';
            app.AmplitudeSlant = uislider(app.Panel_2);
            app.AmplitudeSlant.Limits = [-1 1];
            app.AmplitudeSlant.MajorTicks = [-1 0 1];
            app.AmplitudeSlant.MajorTickLabels = {'Leftward', '0', 'Rightward'};
            app.AmplitudeSlant.ValueChangedFcn = createCallbackFcn(app, @AmplitudeSlantValueChanged, true);
            app.AmplitudeSlant.Value = -0.8;
            app.Panel = uipanel(app.GradientsPanel);
            app.NoiseEnable = uibutton(app.Panel, 'state');
            app.NoiseEnable.ValueChangedFcn = createCallbackFcn(app, @NoiseEnableValueChanged, true);
            app.NoiseEnable.Text = 'Noise Gradient';
            app.NoiseShape = uibuttongroup(app.Panel);
            app.NoiseShape.SelectionChangedFcn = createCallbackFcn(app, @NoiseShapeSelectionChanged, true);
            app.noiseLinearButton = uiradiobutton(app.NoiseShape);
            app.noiseLinearButton.Text = 'linear';
            app.noiseLinearButton.Value = true;
            app.noiseLogButton = uiradiobutton(app.NoiseShape);
            app.noiseLogButton.Text = 'log';
            app.NoiseSlant = uislider(app.Panel);
            app.NoiseSlant.Limits = [-1 1];
            app.NoiseSlant.MajorTicks = [-1 0 1];
            app.NoiseSlant.MajorTickLabels = {'Leftward', '0', 'Rightward'};
            app.NoiseSlant.ValueChangedFcn = createCallbackFcn(app, @NoiseSlantValueChanged, true);
            app.NoiseSlant.Value = -0.8;
            app.TexturePanel = uipanel(app.UIFigure);
            app.TexturePanel.Title = 'Texture';
            app.DefaultSpatialFrequencyLabel = uilabel(app.TexturePanel);
            app.DefaultSpatialFrequencyLabel.Text = 'Default Spatial Frequency:';
            app.DefaultFrequency = uislider(app.TexturePanel);
            app.DefaultFrequency.Limits = [-1 0.3];
            app.DefaultFrequency.MajorTicks = [-1 -0.35 0.3];
            app.DefaultFrequency.MajorTickLabels = {'low', '', 'high'};
            app.DefaultFrequency.ValueChangedFcn = createCallbackFcn(app, @DefaultFrequencyValueChanged, true);
            app.DefaultFrequency.Value = -0.35;
            app.UIAxes = uiaxes(app.TexturePanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XLim = [0 1];
            app.UIAxes.YLim = [0 1];
            app.UIAxes.ZLim = [0 1];
            app.UIAxes.CLim = [0 1];
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes.YTick = [0 0.5 1];
            app.UIAxes.ZTick = [0 0.5 1];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end