classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        effects               matlab.ui.container.Panel
        freqPanel             matlab.ui.container.Panel
        FrequencyStrength     matlab.ui.control.Slider
        FrequencyLabel        matlab.ui.control.Label
        ampPanel              matlab.ui.container.Panel
        AmplitudeStrength     matlab.ui.control.Slider
        AmplitudeLabel        matlab.ui.control.Label
        noisePanel            matlab.ui.container.Panel
        NoiseStrength         matlab.ui.control.Slider
        NoiseLabel            matlab.ui.control.Label
        experimenterControls  matlab.ui.container.Panel
        UIAxes                matlab.ui.control.UIAxes
        DefaultSpatialFrequencybumpsmmLabel  matlab.ui.control.Label
        DefaultFrequency      matlab.ui.control.Slider
        Switch                matlab.ui.control.Switch
        controls              matlab.ui.container.Panel
        RatingSlider          matlab.ui.control.Slider
        NextTrialButton       matlab.ui.control.Button
        VeryHardLabel         matlab.ui.control.Label
        trialLabel            matlab.ui.control.Label
        VeryEasyLabel         matlab.ui.control.Label
    end


    properties (Access = private)
        port;               % tpad port
        x; fs;              % texture parameters (static)
        a; f; y; n; s;      % texture parameters (variable)
        f1;f2; a1;a2; n1;n2;    % strenth parameters
        plotDisplay;            % variable for turning plot display on/off
        rating; gradientstrength; texture; df;  % parameters to save
        trial;                  % keep track of trial #
        maxtrials = 7;          % total # of trials in experiment
        whichGradients;         % which gradients are used for each trial
    end

    methods (Access = private)
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    % BUILD TEXTURE   
        function buildTexture(app)
            %FREQUENCY COMPONENT
            app.s = chirp(app.x,app.f1,app.x(end),app.f2,'logarithmic');
            %AMPLITUDE COMPONENT
            app.a = linspace(app.a1,app.a2,length(app.x));
            %NOISE COMPONENT
            app.n = ones(1,length(app.x));
            rng(1)	% seed random generator so we always get the same array    
            probs = linspace(app.n1,app.n2,length(app.x));
            for i = 1:length(app.x)
                app.n(i) = rand(1)>probs(i);
            end
            %COMBINE EFFECTS
            app.y = app.n.*app.a.*app.s;
        end
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%   
    % UPDATE TEXTURE DISPLAY
        function updateTexture(app)
            %plot
            if app.plotDisplay(1:2) == 'On'
                plot(app.UIAxes,app.x,app.y);
            else
                plot(app.UIAxes,0,0)
            end
            
            %send to tpad
            roughness = 2^15*app.y;
            DCfriction = 2^15;
            gradient = zeros(length(roughness),1);
            if app.port ~= 1
                [result] = loadData(app.port,DCfriction,gradient,roughness);
            end
        end
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 
    % SET AMOUNT OF STRENGTH FOR GRADIENTS
        function setStrength(app)
            %max range: 10^-1 to 10^.4 /mm (= .1 to 2.5 /mm)
            center = app.DefaultFrequency.Value; %default=-0.3 (freq=10^-.3= 0.5/mm)
            lower = center - (-1);
            upper = 0.4 - center;
            app.f1 = 10^(center - app.FrequencyStrength.Value*lower) - 0.00001; 
            app.f2 = 10^(center + app.FrequencyStrength.Value*upper);
            
            %max range: 0 to 1
            app.a1 = 1 - app.AmplitudeStrength.Value; 
            app.a2 = 1 + 0.00001;
            
            %max range: 0 to .8 (units are "probability of a zero drop")
            app.n1 = 0;
            app.n2 = app.NoiseStrength.Value/1.25;
        end
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    % ENABLE/DISABLE SLIDERS    
        function enableSliders(app)
            sliders = app.whichGradients(app.trial,:);
            for i = 1:3
                if sliders(1)==1;   app.FrequencyStrength.Enable = 'on';
                else                app.FrequencyStrength.Enable = 'off'; end
                
                if sliders(2)==1;   app.AmplitudeStrength.Enable = 'on';
                else                app.AmplitudeStrength.Enable = 'off'; end
                
                if sliders(3)==1;   app.NoiseStrength.Enable = 'on';
                else                app.NoiseStrength.Enable = 'off'; end
                
                
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %connect to tpad
            d = [19200 5.3]; app.port = 1;
            [p, d] = connect2tpad('COM4'); app.port = p;
            
            %initialize default parameters
            app.x = (0:d(1)-1)*d(2)*1e-3;   %space array (mm)
            app.f = 10^app.DefaultFrequency.Value;
            app.a = ones(size(app.x)); 
            app.n = ones(1,length(app.x));
            app.s = sin(2*pi*app.f*app.x);  %initial texture array
            app.trial = 1;
            
            %intialize gradient strength amounts
            setStrength(app)
            
            %initialize slider enable/disable
            app.whichGradients = [1 0 0; 0 1 0; 0 0 1;...
                                  1 1 0; 1 0 1; 0 1 1; 1 1 1];
            enableSliders(app);
            
            %set up plot display
            app.UIAxes.XLim = [0 104]; app.UIAxes.YLim = [-1.2 1.2];
            xlabel(app.UIAxes, 'Position (mm)');
            ylabel(app.UIAxes, 'Friction Force');
            app.UIAxes.YTick = [];
            title(app.UIAxes,'');
            app.plotDisplay = 'Off';
            
            %set up parameters to save
            app.rating = zeros(7,1);
            app.gradientstrength = zeros(7,3);
            app.texture = zeros(7,length(app.x)); 
            app.df = zeros(7,1);
            
            %load default texture onto tpad
            buildTexture(app)
            updateTexture(app)
        end

        % Value changed function: DefaultFrequency
        function DefaultFrequencyValueChanged(app, event)
            %SET DEFAULT FREQUENCY OF TEXTURE (UNRELATED TO GRADIENTS)
            app.DefaultFrequency.Value = round((app.DefaultFrequency.Value)*10)/10;
            app.f = app.DefaultFrequency.Value;
            setStrength(app)
            buildTexture(app)
            updateTexture(app)
        end

        % Value changed function: AmplitudeStrength
        function AmplitudeStrengthValueChanged(app, event)
            %CHANGE AMPLITUDE GRADIENT STRENGTH DIRECTION (Stronger/weaker)
            setStrength(app)
            buildTexture(app)
            updateTexture(app)
        end

        % Value changed function: FrequencyStrength
        function FrequencyStrengthValueChanged(app, event)
            %CHANGE FREQUENCY GRADIENT STRENGTH DIRECTION (Stronger/weaker)
            setStrength(app)
            buildTexture(app)
            updateTexture(app)
        end

        % Value changed function: NoiseStrength
        function NoiseStrengthValueChanged(app, event)
            %CHANGE NOISE GRADIENT STRENGTH DIRECTION (Stronger/weaker)
            setStrength(app)
            buildTexture(app)
            updateTexture(app)
        end

        % Value changed function: RatingSlider
        function RatingSliderValueChanged(app, event)
            app.RatingSlider.Value=round(app.RatingSlider.Value); 
        end

        % Button pushed function: NextTrialButton
        function NextTrialButtonPushed(app, event)
            %save current values
            app.gradientstrength(app.trial,:) = [app.FrequencyStrength.Value ...
                                                 app.AmplitudeStrength.Value ...
                                                 app.NoiseStrength.Value];                                
            app.rating(app.trial) = app.RatingSlider.Value;
            app.texture(app.trial,:) = app.y;
            app.df(app.trial) = 10^app.DefaultFrequency.Value;
            %prep for next trial
            if app.trial < app.maxtrials
                app.trial = app.trial + 1;
                app.trialLabel.Text = strcat('Current Trial: ',num2str(app.trial));
                app.RatingSlider.Value = 4;
                app.FrequencyStrength.Value=0;
                app.AmplitudeStrength.Value=0;
                app.NoiseStrength.Value=0;
                enableSliders(app)
                setStrength(app); buildTexture(app); updateTexture(app);
            %finish experiment and save
            elseif app.trial == app.maxtrials
                app.trialLabel.Text = 'Current Trial:Finished';
                app.trial = app.trial + 1;
                %save stuff:
                c = clock;
                filename = strcat(num2str(c(2:5)),'output.mat');
                gradientStrength = app.gradientstrength;
                rating = app.rating;
                texture = app.texture;
                defaultFreq = app.df;
                save(filename,'gradientStrength','rating','texture','defaultFreq')             
            end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            app.plotDisplay = app.Switch.Value;
            
            %update plot
            if app.plotDisplay(1:2) == 'On'
                plot(app.UIAxes,app.x,app.y);
            else
                plot(app.UIAxes,0,0)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.effects = uipanel(app.UIFigure);
            app.effects.Title = 'Effects';
            app.freqPanel = uipanel(app.effects);
            app.FrequencyStrength = uislider(app.freqPanel);
            app.FrequencyStrength.Limits = [0 1];
            app.FrequencyStrength.MajorTicks = [0 1];
            app.FrequencyStrength.MajorTickLabels = {'', '', ''};
            app.FrequencyStrength.ValueChangedFcn = createCallbackFcn(app, @FrequencyStrengthValueChanged, true);
            app.FrequencyStrength.MinorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.FrequencyLabel = uilabel(app.freqPanel);
            app.FrequencyLabel.Text = 'Gradient 1';
            app.ampPanel = uipanel(app.effects);
            app.AmplitudeStrength = uislider(app.ampPanel);
            app.AmplitudeStrength.Limits = [0 1];
            app.AmplitudeStrength.MajorTicks = [0 1];
            app.AmplitudeStrength.MajorTickLabels = {''};
            app.AmplitudeStrength.ValueChangedFcn = createCallbackFcn(app, @AmplitudeStrengthValueChanged, true);
            app.AmplitudeStrength.MinorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.AmplitudeLabel = uilabel(app.ampPanel);
            app.AmplitudeLabel.Text = 'Gradient 2';
            app.noisePanel = uipanel(app.effects);
            app.NoiseStrength = uislider(app.noisePanel);
            app.NoiseStrength.Limits = [0 1];
            app.NoiseStrength.MajorTicks = [0 1];
            app.NoiseStrength.MajorTickLabels = {''};
            app.NoiseStrength.ValueChangedFcn = createCallbackFcn(app, @NoiseStrengthValueChanged, true);
            app.NoiseStrength.MinorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.NoiseLabel = uilabel(app.noisePanel);
            app.NoiseLabel.Text = 'Gradient 3';
            app.experimenterControls = uipanel(app.UIFigure);
            app.experimenterControls.Title = 'Experimenter Controls';
            app.UIAxes = uiaxes(app.experimenterControls);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.DefaultSpatialFrequencybumpsmmLabel = uilabel(app.experimenterControls);
            app.DefaultSpatialFrequencybumpsmmLabel.Text = 'Default Spatial Frequency (bumps/mm):';
            app.DefaultFrequency = uislider(app.experimenterControls);
            app.DefaultFrequency.Limits = [-1 0.4];
            app.DefaultFrequency.MajorTicks = [-1 -0.7 -0.3 0.1 0.4];
            app.DefaultFrequency.MajorTickLabels = {'0.1', '0.2', '0.5', '1.3', '2.5'};
            app.DefaultFrequency.ValueChangedFcn = createCallbackFcn(app, @DefaultFrequencyValueChanged, true);
            app.DefaultFrequency.MinorTicks = [-1 -0.9 -0.8 -0.7 -0.6 -0.5 -0.4 -0.3 -0.2 -0.1 0 0.1 0.2 0.3 0.4];
            app.DefaultFrequency.Value = -0.3;
            app.Switch = uiswitch(app.experimenterControls, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.controls = uipanel(app.UIFigure);
            app.controls.Title = 'Rating';
            app.RatingSlider = uislider(app.controls);
            app.RatingSlider.Limits = [1 7];
            app.RatingSlider.MajorTicks = [1 2 3 4 5 6 7];
            app.RatingSlider.ValueChangedFcn = createCallbackFcn(app, @RatingSliderValueChanged, true);
            app.RatingSlider.MinorTicks = [];
            app.RatingSlider.Value = 4;
            app.NextTrialButton = uibutton(app.controls, 'push');
            app.NextTrialButton.ButtonPushedFcn = createCallbackFcn(app, @NextTrialButtonPushed, true);
            app.NextTrialButton.Text = 'Next Trial';
            app.VeryHardLabel = uilabel(app.controls);
            app.VeryHardLabel.Text = 'Very Hard';
            app.trialLabel = uilabel(app.controls);
            app.trialLabel.Text = 'Current Trial:1';
            app.VeryEasyLabel = uilabel(app.controls);
            app.VeryEasyLabel.Text = 'Very Easy';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end